classdef typography < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        PreviewImageButton       matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        ImagePathEditFieldLabel  matlab.ui.control.Label
        ImagePathEditField       matlab.ui.control.EditField
        PreviewLabel             matlab.ui.control.Label
        FindFontButton           matlab.ui.control.Button
        SearchButton             matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        FontMatchesLabel         matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Label_3                  matlab.ui.control.Label
        Image2                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Font3Label               matlab.ui.control.Label
        Font2Label               matlab.ui.control.Label
        Font1Label               matlab.ui.control.Label
        MatchLabel               matlab.ui.control.Label
        MatchLabel_2             matlab.ui.control.Label
        MatchLabel_3             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PreviewImageButton
        function updateImagePreview(app, event)
            app.Image.Visible=true;
            exampleImage=app.ImagePathEditField.Value;
            %img=imread(exampleImage);
            app.Image.ImageSource=exampleImage;
            
        end

        % Button pushed function: FindFontButton
        function FindFontButtonPushed(app, event)
            findImage=imread(app.ImagePathEditField.Value);
            
            %send read in image to algorithm
            img=imread(findImage);
            getChars(img);
            
            font=findMatchFont();
            app.Font1Label.Text=font;
            app.Font1Label.Visible=true;
            
            %get example Image
            
            imgPath=append('Font/',font,'/example.png');
            
            app.Image2.ImageSource=imgPath;
            app.Image2.Visible=true;
            
            
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            [base,folder]=uigetfile('.png'); 
        
            app.ImagePathEditField.Value=fullfile(folder,base);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {339, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PreviewImageButton = uibutton(app.LeftPanel, 'push');
            app.PreviewImageButton.ButtonPushedFcn = createCallbackFcn(app, @updateImagePreview, true);
            app.PreviewImageButton.Text = 'Preview Image';
            app.Image = uiimage(app.LeftPanel);
            app.Image.Visible = 'off';
            app.ImagePathEditFieldLabel = uilabel(app.LeftPanel);
            app.ImagePathEditFieldLabel.Text = 'Image Path';
            app.ImagePathEditField = uieditfield(app.LeftPanel, 'text');
            app.PreviewLabel = uilabel(app.LeftPanel);
            app.PreviewLabel.Text = 'Preview';
            app.FindFontButton = uibutton(app.LeftPanel, 'push');
            app.FindFontButton.ButtonPushedFcn = createCallbackFcn(app, @FindFontButtonPushed, true);
            app.FindFontButton.Text = 'Find Font';
            app.SearchButton = uibutton(app.LeftPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.RightPanel = uipanel(app.GridLayout);
            app.FontMatchesLabel = uilabel(app.RightPanel);
            app.FontMatchesLabel.Text = 'Font Matches';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '1. ';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '2.';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '3.';
            app.Image2 = uiimage(app.RightPanel);
            app.Image2.Visible = 'off';
            app.Image3 = uiimage(app.RightPanel);
            app.Image3.Visible = 'off';
            app.Image4 = uiimage(app.RightPanel);
            app.Image4.Visible = 'off';
            app.Font3Label = uilabel(app.RightPanel);
            app.Font3Label.Visible = 'off';
            app.Font3Label.Text = 'Font 3';
            app.Font2Label = uilabel(app.RightPanel);
            app.Font2Label.Visible = 'off';
            app.Font2Label.Text = 'Font 2';
            app.Font1Label = uilabel(app.RightPanel);
            app.Font1Label.Visible = 'off';
            app.Font1Label.Text = 'Font 1';
            app.MatchLabel = uilabel(app.RightPanel);
            app.MatchLabel.Visible = 'off';
            app.MatchLabel.Text = '#1 % Match';
            app.MatchLabel_2 = uilabel(app.RightPanel);
            app.MatchLabel_2.Visible = 'off';
            app.MatchLabel_2.Text = '#2 % Match';
            app.MatchLabel_3 = uilabel(app.RightPanel);
            app.MatchLabel_3.Visible = 'off';
            app.MatchLabel_3.Text = '#3 % Match';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = typography

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end